classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ConnectionTab_2            matlab.ui.container.Tab
        LogTextArea                matlab.ui.control.TextArea
        SoftwareopenPanel          matlab.ui.container.Panel
        SoftwareBeginLabel         matlab.ui.control.Label
        ProgramStartLamp           matlab.ui.control.Lamp
        ProgramStartLampLabel      matlab.ui.control.Label
        SimopenCheckBox            matlab.ui.control.CheckBox
        switch_beginProgramSwitch  matlab.ui.control.Switch
        NetworkPanel               matlab.ui.container.Panel
        PortEditField              matlab.ui.control.EditField
        PortEditFieldLabel         matlab.ui.control.Label
        IPEditField                matlab.ui.control.EditField
        IPEditFieldLabel           matlab.ui.control.Label
        WificonnectionLamp         matlab.ui.control.Lamp
        WificonnectionLampLabel    matlab.ui.control.Label
        NetworkConnectionLabel     matlab.ui.control.Label
        ConnectButton              matlab.ui.control.StateButton
        CalibrationTab             matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %==================================
        %====    System parameter      ====
        %==================================
        % Simulink
        mdl = "proc"; demonst = false;

        % Network 
        ip; port;
        
        %==================================
        %=====    Robot control       =====
        %==================================      

        robot_speed;
        robot_steerAngle;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %==================================
            %=======    Initialize      =======
            %==================================
            
            % Program initialization
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Value = gui_logout_str("GUI is opened!!");
            [left_road,right_road] = draw_road();
            plot(app.UIAxes,left_road(1,:),left_road(2,:)); 
            hold on         
            plot(app.UIAxes,right_road(1,:),right_road(2,:));

            % Simulink initialization
            app.ProgramStartLamp.Color = [0.4 0.4 0.4];

            % Network initialization
            app.ip = "192.168.211.254";
            app.port = 5000;
            app.WificonnectionLamp.Color = [0.4 0.4 0.4];
            app.IPEditField.Value = app.ip;
            app.PortEditField.Value = string(app.port);
        end

        % Value changed function: switch_beginProgramSwitch
        function switch_beginProgramSwitchValueChanged(app, event)
            value = app.switch_beginProgramSwitch.Value;
            
            % Main action - interact with simulink
            if strcmp(value,"On")
                if app.SimopenCheckBox.Value
                    open_system(app.mdl); % Open simulink
                else
                    load_system(app.mdl); % Load simulink - not open
                end
                app.SimopenCheckBox.Enable = "off";
            else
                    set_param(app.mdl,SimulationCommand="Stop")
                    save_system(app.mdl)
                    close_system(app.mdl) % Close simulink
            end

            % Update status
            if strcmp(value,"On")
                app.SimopenCheckBox.Enable = 'off';
                app.ProgramStartLamp.Color = 'yellow';
            else
                app.SimopenCheckBox.Enable = 'on';
                app.ProgramStartLamp.Color = [0.4 0.4 0.4];
            end
        end

        % Value changed function: SimopenCheckBox
        function SimopenCheckBoxValueChanged(app, event)
            value = app.SimopenCheckBox.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete Simulink
            close_system(); 
            
            delete(app)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if key == 'w'
                app.robot_speed = 512;
            end

            if key == 's'
                app.robot_speed = -512;
            end    

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectionTab_2 = uitab(app.TabGroup);
            app.ConnectionTab_2.Title = 'Connection';
            app.NetworkPanel = uipanel(app.ConnectionTab_2);
            app.NetworkPanel.Title = 'Network';
            app.ConnectButton = uibutton(app.NetworkPanel, 'state');
            app.ConnectButton.Text = 'Connect';
            app.NetworkConnectionLabel = uilabel(app.NetworkPanel);
            app.NetworkConnectionLabel.Text = 'Network Connection';
            app.WificonnectionLampLabel = uilabel(app.NetworkPanel);
            app.WificonnectionLampLabel.Text = 'Wifi connection';
            app.WificonnectionLamp = uilamp(app.NetworkPanel);
            app.IPEditFieldLabel = uilabel(app.NetworkPanel);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.NetworkPanel, 'text');
            app.PortEditFieldLabel = uilabel(app.NetworkPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.NetworkPanel, 'text');
            app.SoftwareopenPanel = uipanel(app.ConnectionTab_2);
            app.SoftwareopenPanel.Title = 'Software open';
            app.switch_beginProgramSwitch = uiswitch(app.SoftwareopenPanel, 'slider');
            app.switch_beginProgramSwitch.ValueChangedFcn = createCallbackFcn(app, @switch_beginProgramSwitchValueChanged, true);
            app.SimopenCheckBox = uicheckbox(app.SoftwareopenPanel);
            app.SimopenCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimopenCheckBoxValueChanged, true);
            app.SimopenCheckBox.Text = 'Sim. open';
            app.ProgramStartLampLabel = uilabel(app.SoftwareopenPanel);
            app.ProgramStartLampLabel.Text = 'Program Start';
            app.ProgramStartLamp = uilamp(app.SoftwareopenPanel);
            app.SoftwareBeginLabel = uilabel(app.SoftwareopenPanel);
            app.SoftwareBeginLabel.Text = 'Software Begin';
            app.LogTextArea = uitextarea(app.ConnectionTab_2);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end